classdef Subapp_802_11a_g < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        agWaveGeneratorUIFigure        matlab.ui.Figure
        SampleRateTextArea             matlab.ui.control.TextArea
        SampleRateTextAreaLabel        matlab.ui.control.Label
        wlan_config                    matlab.ui.control.DropDown
        DropDownLabel                  matlab.ui.control.Label
        ExportbkwformatButton          matlab.ui.control.Button
        OversamplingFactorEditField    matlab.ui.control.NumericEditField
        OversamplingFactorEditFieldLabel  matlab.ui.control.Label
        MCSDropDown                    matlab.ui.control.DropDown
        PSDUlengthBytesEditField       matlab.ui.control.NumericEditField
        MCSDropDownLabel               matlab.ui.control.Label
        PSDUlengthBytesEditFieldLabel  matlab.ui.control.Label
        ChannelBandwidthDropDown       matlab.ui.control.DropDown
        ChannelBandwidthDropDownLabel  matlab.ui.control.Label
        ModulationDropDown             matlab.ui.control.DropDown
        ModulationDropDownLabel        matlab.ui.control.Label
        agNonHTOFDMLabel               matlab.ui.control.Label
        GenerateWaveformButton         matlab.ui.control.Button
        UIAxes_Spectrum                matlab.ui.control.UIAxes
        UIAxes_Tscope                  matlab.ui.control.UIAxes
    end

    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        SimulinkModelName char
    end

    % Private properties that correspond to the Simulink model
    properties (Access = private, Transient)
        SimulinkModel slsim.Model
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %default 조건 설정
            global Modul_value;
            global CBW_value;
            global MCS_value;
            global PSDU_value;
            global OS_value;

            global nonHTCfg;
            global waveform;
            global Fs;

            global numPackets;
            global inbit;
            global tIdle;
            global wintrantime;
            global ScramblerInit;
            global filter;

            Modul_value='OFDM';
            CBW_value='CBW20';
            MCS_value=0;
            PSDU_value=1000;
            OS_value=1;

            %임의값 지정, 필요 시 app option 추가
            numPackets=1;
            inbit=randi([0,1],1000,1);
            tIdle=0;
            wintrantime=1e-7;
            ScramblerInit=93;
            filter = dsp.FIRFilter('Numerator', sinc(0.4*(-25:25)) );

            xlabel(app.UIAxes_Spectrum, 'Frequency (Hz)');
            ylabel(app.UIAxes_Spectrum, 'Power (dB)');
            title(app.UIAxes_Spectrum, 'Frequency Spectrum');

            xlabel(app.UIAxes_Tscope,'TIme(s)');
            ylabel(app.UIAxes_Tscope,'Amplitude');     
            title(app.UIAxes_Tscope, 'Time Scope');

        end

        % Value changed function: MCSDropDown
        function MCSDropDownValueChanged(app, event)
            global MCS_value;
            MCS_value = str2double(app.MCSDropDown.Value);
        end

        % Value changed function: PSDUlengthBytesEditField
        function PSDUlengthBytesEditFieldValueChanged(app, event)
            global PSDU_value;
            PSDU_value = app.PSDUlengthBytesEditField.Value;
        end

        % Button pushed function: GenerateWaveformButton
        function GenerateWaveformButtonPushed(app, event)
            global Modul_value;
            global CBW_value;
            global MCS_value;
            global PSDU_value;
            global OS_value;

            global nonHTCfg;
            global waveform;
            global Fs;

            global numPackets;
            global inbit;
            global tIdle;
            global wintrantime;
            global ScramblerInit;
            global filter;
            
            % define configuration
            nonHTCfg = wlanNonHTConfig('Modulation', Modul_value, ...
                'ChannelBandwidth', CBW_value, ...
                'MCS', MCS_value, ...
                'PSDULength', PSDU_value);
            
            % generate waveform
            waveform = wlanWaveformGenerator(inbit, nonHTCfg, ...
                'NumPackets', numPackets, ...
                'IdleTime', tIdle, ...
                'OversamplingFactor', OS_value, ...
                'ScramblerInitialization', ScramblerInit, ...
                'WindowTransitionTime', wintrantime);

            Fs=wlanSampleRate(nonHTCfg, 'OversamplingFactor', OS_value);
            printFs=[num2str(Fs/1000000),'MHz'];
            app.SampleRateTextArea.Value=printFs;
            
            % 필터링:
            
            waveform = filter(waveform);
           
            % visulization - spectrum analyzer
            % spectrum = spectrumAnalyzer('SampleRate', Fs);
            % spectrum(waveform);
            % release(spectrum);

            % % visulization - timescope
            % timeScope = timescope('SampleRate', Fs, ...
            %     'TimeSpanOverrunAction', 'scroll', ...
            %     'TimeSpanSource', 'property', ...
            %     'TimeSpan', 1.5e-06);
            % timeScope(waveform);
            % release(timeScope);
            
            % App designer 창에 plot하기 위해 freq/time data 생성
            L = length(waveform); % 신호 길이
            f = (-Fs/2):(Fs/L):(Fs/2 - Fs/L); % 주파수 벡터 생성 (-Fs/2부터 Fs/2까지)
            Y = fftshift(fft(waveform)); % FFT 수행 및 주파수 이동
            P = abs(Y/L); 
            P = P(1:length(f));
            
            plot(app.UIAxes_Spectrum, f, 10*log10(P));
            xlabel(app.UIAxes_Spectrum, 'Frequency (Hz)');
            ylabel(app.UIAxes_Spectrum, 'Power (dB)');
            title(app.UIAxes_Spectrum, 'Frequency Spectrum');
           
            t=(0:L-1)*(1/Fs);
            plot(app.UIAxes_Tscope,t,real(waveform),t,imag(waveform));
            xlabel(app.UIAxes_Tscope,'TIme(s)');
            ylabel(app.UIAxes_Tscope,'Amplitude');            
            legend(app.UIAxes_Tscope,'Real','Imag')
            
        end

        % Value changed function: OversamplingFactorEditField
        function OversamplingFactorEditFieldValueChanged(app, event)
            global OS_value;
            OS_value = app.OversamplingFactorEditField.Value;
        end

        % Button pushed function: ExportbkwformatButton
        function ExportbkwformatButtonPushed(app, event)
            global waveform;
            global Fs;
            global CBW_value;
            global MCS_value;

            MCS_text = num2str(MCS_value);

            filename =['802.11',app.wlan_config.Value,CBW_value,'_MCS',num2str(MCS_value),'.bkw']; 
            % 파일을 쓰기 모드로 열기
            fileID = fopen(filename, 'w');
            
            %bkw file format 작성??
            bkwheader1='bkwaveformatv0p1';

            bkwheader2='0000............';
            
            strFs = num2str(Fs);
            RATEstr = '000000000000';
            RATEstr(end-length(strFs)+1:end) = strFs(1:end);
            bkwheader3=['RATE',RATEstr];

            strlengthwaveform = num2str(length(waveform));
            LENGTHstr = '0000000000';
            LENGTHstr(end-length(strlengthwaveform)+1:end) = strlengthwaveform(1:end);
            bkwheader4=['LENGTH',LENGTHstr];

            bkwheader5='reserved........';
            
            bkwheader=[bkwheader1,bkwheader2,bkwheader3,bkwheader4,bkwheader5];
            ascii_bkw=double(bkwheader);
            fwrite(fileID,ascii_bkw ,'uint8');

            %waveform real / imag값 각각 저장
            real_wf=real(waveform);
            imag_wf=imag(waveform);

            % 두 배열을 번갈아가며 파일에 쓰기
            for i = 1:length(waveform)
                fwrite(fileID, real_wf(i), 'double');
                fwrite(fileID, imag_wf(i), 'double');
            end
            
            % 파일 닫기
            fclose(fileID);
        end
    end

    % Simulink methods
    methods

        % Associate the Simulink model with the app components
        function set.SimulinkModelName(app, value)

            app.SimulinkModelName = value;
            app.SimulinkModel = slsim.Model(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.agWaveGeneratorUIFigure = uifigure('Visible', 'off');
            app.agWaveGeneratorUIFigure.Name = '802.11a/g WaveGenerator';
            app.UIAxes_Tscope = uiaxes(app.agWaveGeneratorUIFigure);
            title(app.UIAxes_Tscope, 'Time Scope')
            xlabel(app.UIAxes_Tscope, 'X')
            ylabel(app.UIAxes_Tscope, 'Y')
            zlabel(app.UIAxes_Tscope, 'Z')
            app.UIAxes_Spectrum = uiaxes(app.agWaveGeneratorUIFigure);
            title(app.UIAxes_Spectrum, 'Spectrum Analyzer')
            xlabel(app.UIAxes_Spectrum, 'X')
            ylabel(app.UIAxes_Spectrum, 'Y')
            zlabel(app.UIAxes_Spectrum, 'Z')
            app.GenerateWaveformButton = uibutton(app.agWaveGeneratorUIFigure, 'push');
            app.GenerateWaveformButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateWaveformButtonPushed, true);
            app.GenerateWaveformButton.Text = 'Generate Waveform';
            app.agNonHTOFDMLabel = uilabel(app.agWaveGeneratorUIFigure);
            app.agNonHTOFDMLabel.Text = {'802.11 a/g'; '(Non-HT / OFDM)'};
            app.ModulationDropDownLabel = uilabel(app.agWaveGeneratorUIFigure);
            app.ModulationDropDownLabel.Text = 'Modulation';
            app.ModulationDropDown = uidropdown(app.agWaveGeneratorUIFigure);
            app.ModulationDropDown.Items = {'OFDM'};
            app.ModulationDropDown.Value = 'OFDM';
            app.ChannelBandwidthDropDownLabel = uilabel(app.agWaveGeneratorUIFigure);
            app.ChannelBandwidthDropDownLabel.Text = 'Channel Bandwidth';
            app.ChannelBandwidthDropDown = uidropdown(app.agWaveGeneratorUIFigure);
            app.ChannelBandwidthDropDown.Items = {'20MHz'};
            app.ChannelBandwidthDropDown.Value = '20MHz';
            app.PSDUlengthBytesEditFieldLabel = uilabel(app.agWaveGeneratorUIFigure);
            app.PSDUlengthBytesEditFieldLabel.Text = 'PSDU length(Bytes)';
            app.MCSDropDownLabel = uilabel(app.agWaveGeneratorUIFigure);
            app.MCSDropDownLabel.Text = 'MCS';
            app.PSDUlengthBytesEditField = uieditfield(app.agWaveGeneratorUIFigure, 'numeric');
            app.PSDUlengthBytesEditField.Limits = [1 4095];
            app.PSDUlengthBytesEditField.ValueChangedFcn = createCallbackFcn(app, @PSDUlengthBytesEditFieldValueChanged, true);
            app.PSDUlengthBytesEditField.Value = 1000;
            app.MCSDropDown = uidropdown(app.agWaveGeneratorUIFigure);
            app.MCSDropDown.Items = {'0', '1', '2', '3', '4', '5', '6', '7'};
            app.MCSDropDown.ValueChangedFcn = createCallbackFcn(app, @MCSDropDownValueChanged, true);
            app.MCSDropDown.Value = '0';
            app.OversamplingFactorEditFieldLabel = uilabel(app.agWaveGeneratorUIFigure);
            app.OversamplingFactorEditFieldLabel.Text = 'Oversampling Factor';
            app.OversamplingFactorEditField = uieditfield(app.agWaveGeneratorUIFigure, 'numeric');
            app.OversamplingFactorEditField.Limits = [1 Inf];
            app.OversamplingFactorEditField.ValueChangedFcn = createCallbackFcn(app, @OversamplingFactorEditFieldValueChanged, true);
            app.OversamplingFactorEditField.Value = 1;
            app.ExportbkwformatButton = uibutton(app.agWaveGeneratorUIFigure, 'push');
            app.ExportbkwformatButton.ButtonPushedFcn = createCallbackFcn(app, @ExportbkwformatButtonPushed, true);
            app.ExportbkwformatButton.Text = 'Export(.bkw format)';
            app.DropDownLabel = uilabel(app.agWaveGeneratorUIFigure);
            app.DropDownLabel.Text = '802.11';
            app.wlan_config = uidropdown(app.agWaveGeneratorUIFigure);
            app.wlan_config.Items = {'a', 'g'};
            app.wlan_config.Value = 'a';
            app.SampleRateTextAreaLabel = uilabel(app.agWaveGeneratorUIFigure);
            app.SampleRateTextAreaLabel.Text = 'Sample Rate';
            app.SampleRateTextArea = uitextarea(app.agWaveGeneratorUIFigure);
            app.SampleRateTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.agWaveGeneratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Subapp_802_11a_g

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.agWaveGeneratorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.agWaveGeneratorUIFigure)
        end
    end
end