classdef Subapp_802_11n_ac < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        bkwfilegenerateButton           matlab.ui.control.Button
        MHzLabel                        matlab.ui.control.Label
        SamplerateLabel                 matlab.ui.control.Label
        WindowtransitiontimesEditField  matlab.ui.control.NumericEditField
        WindowtransitiontimesEditFieldLabel  matlab.ui.control.Label
        ScramblerinitializationEditField  matlab.ui.control.NumericEditField
        ScramblerinitializationLabel    matlab.ui.control.Label
        OversamplingfactorEditField     matlab.ui.control.NumericEditField
        OversamplingfactorEditField_2Label  matlab.ui.control.Label
        IdletimesEditField              matlab.ui.control.NumericEditField
        IdletimesLabel                  matlab.ui.control.Label
        PacketsEditField                matlab.ui.control.NumericEditField
        PacketsLabel                    matlab.ui.control.Label
        SpatialmappingmatrixEditField   matlab.ui.control.NumericEditField
        SpatialmappingmatrixEditFieldLabel  matlab.ui.control.Label
        PartialAIDEditField             matlab.ui.control.NumericEditField
        PartialAIDEditFieldLabel        matlab.ui.control.Label
        GroupIDEditField                matlab.ui.control.NumericEditField
        GroupIDEditFieldLabel           matlab.ui.control.Label
        GuardintervalDropDown           matlab.ui.control.DropDown
        GuardintervalDropDownLabel      matlab.ui.control.Label
        APEPlengthbytesEditField        matlab.ui.control.NumericEditField
        APEPlengthbytesEditFieldLabel   matlab.ui.control.Label
        ChannelcodingDropDown           matlab.ui.control.DropDown
        ChannelcodingDropDownLabel      matlab.ui.control.Label
        MCSDropDown                     matlab.ui.control.DropDown
        MCSDropDownLabel                matlab.ui.control.Label
        SpatialmappingDropDown          matlab.ui.control.DropDown
        SpatialmappingDropDownLabel     matlab.ui.control.Label
        UsersEditField                  matlab.ui.control.NumericEditField
        UsersEditFieldLabel             matlab.ui.control.Label
        ChannelbandwidthDropDown        matlab.ui.control.DropDown
        ChannelbandwidthLabel           matlab.ui.control.Label
        WLAN80211acSingleUserVHTconfigurationLabel  matlab.ui.control.Label
        GenerateWaveformButton          matlab.ui.control.Button
        BeamformingCheckBox             matlab.ui.control.CheckBox
        UIAxes_Tscope                   matlab.ui.control.UIAxes
        UIAxes_Spectrum                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.bkwfilegenerateButton.Visible = "off";

            app.BeamformingCheckBox.Visible ='off';
            app.BeamformingCheckBox.Value = 1;
            
            app.SpatialmappingmatrixEditField.Visible = 'off';
            app.SpatialmappingmatrixEditFieldLabel.Visible = 'off';
            app.SpatialmappingmatrixEditField.Value = 1;

            app.APEPlengthbytesEditField.Value = 1024;
            app.GroupIDEditField.Value = 63;
            app.PartialAIDEditField.Value = 275;
            app.PacketsEditField.Value = 1;
            app.IdletimesEditField.Value = 0;
            app.OversamplingfactorEditField.Value = 1;
            app.ScramblerinitializationEditField.Value = 93;
            app.WindowtransitiontimesEditField.Value = 1e-07;

            xlabel(app.UIAxes_Spectrum, 'Frequency (Hz)');
            ylabel(app.UIAxes_Spectrum, 'Power (dB)');
            title(app.UIAxes_Spectrum, 'Frequency Spectrum');

            xlabel(app.UIAxes_Tscope,'TIme(s)');
            ylabel(app.UIAxes_Tscope,'Amplitude');     
            title(app.UIAxes_Tscope, 'Time Scope');

        end

        % Callback function: GenerateWaveformButton, IdletimesEditField, 
        % ...and 5 other components
        function waveformGenerate(app, event)
            global Fs;
            global waveform;

            ChannelBandwidth_text = app.ChannelbandwidthDropDown.Value;
            Spatialmapping_text = app.SpatialmappingDropDown.Value;
            Spatialmappingmatrix_value = app.SpatialmappingmatrixEditField.Value;
            Beamformingenalbe = app.BeamformingCheckBox.Value;
            MCS_value = app.MCSDropDown.Value;
            Channelcoding_text = app.ChannelcodingDropDown.Value;
            APEP_value = app.APEPlengthbytesEditField.Value;
            Gaurdinterval_text = app.GuardintervalDropDown.Value;
            GroupID_value = app.GroupIDEditField.Value;
            PartialAID_value = app.PartialAIDEditField.Value;

            numPackets_value = app.PacketsEditField.Value;
            Idletime_value = app.IdletimesEditField.Value;
            Oversamplingfactor_value = app.OversamplingfactorEditField.Value;
            Scramblerinitialization_value = app.ScramblerinitializationEditField.Value;
            Windowtransitiontime_value = app.WindowtransitiontimesEditField.Value;
            
            % numrow = count(Spatialmappingmatrix_text,';')+1;
            % 
            % %%%%% 오류 %%%%%
            % if (numrow ~= STS_value)|(numcol ~= NumberofTransmitantennas_value)
            %     errordlg(['Spatial mapping matrix의 size는' ...
            %         'Space-time streams by Transmit Antennas이어야 합니다.'],'오류','modal')
            % end

            if strcmp(Spatialmapping_text,'Custom')
                vhtCfg = wlanVHTConfig('ChannelBandwidth', ChannelBandwidth_text, ...
                    'NumUsers', 1, ...
                    'NumTransmitAntennas', 1, ...
                    'NumSpaceTimeStreams', 1, ...
                    'SpatialMapping', Spatialmapping_text, ...
                    'SpatialMappingMatrix', Spatialmappingmatrix_value, ...
                    'Beamforming', Beamformingenalbe, ...
                    'MCS', MCS_value, ...
                    'ChannelCoding', Channelcoding_text, ...
                    'APEPLength', APEP_value, ...
                    'GuardInterval', Gaurdinterval_text, ...
                    'GroupID', GroupID_value, ...
                    'PartialAID', PartialAID_value);
            else
                vhtCfg = wlanVHTConfig('ChannelBandwidth', ChannelBandwidth_text, ...
                    'NumUsers', 1, ...
                    'NumTransmitAntennas', 1, ...
                    'NumSpaceTimeStreams', 1, ...
                    'SpatialMapping', Spatialmapping_text, ...
                    'MCS', MCS_value, ...
                    'ChannelCoding', Channelcoding_text, ...
                    'APEPLength', APEP_value, ...
                    'GuardInterval', Gaurdinterval_text, ...
                    'GroupID', GroupID_value, ...
                    'PartialAID', PartialAID_value);
            end

            % input bit source:
            in = randi([0, 1], 1000, 1);

            waveform = wlanWaveformGenerator(in, vhtCfg, ...
                'NumPackets', numPackets_value, ...
                'IdleTime', Idletime_value, ...
                'OversamplingFactor', Oversamplingfactor_value, ...
                'ScramblerInitialization', Scramblerinitialization_value, ...
                'WindowTransitionTime', Windowtransitiontime_value);

            Fs = wlanSampleRate(vhtCfg, 'OversamplingFactor', Oversamplingfactor_value);

            L = length(waveform); % 신호 길이
            f = (-Fs/2):(Fs/L):(Fs/2 - Fs/L); % 주파수 벡터 생성 (-Fs/2부터 Fs/2까지)
            Y = fftshift(fft(waveform)); % FFT 수행 및 주파수 이동
            P = abs(Y/L); 
            P = P(1:length(f));

            plot(app.UIAxes_Spectrum, f, 10*log10(P));
            xlabel(app.UIAxes_Spectrum, 'Frequency (Hz)');
            ylabel(app.UIAxes_Spectrum, 'Power (dB)');
            title(app.UIAxes_Spectrum, 'Frequency Spectrum');
           
            t=(0:L-1)*(1/Fs);
            plot(app.UIAxes_Tscope,t,real(waveform),t,imag(waveform));
            xlabel(app.UIAxes_Tscope,'TIme(s)');
            ylabel(app.UIAxes_Tscope,'Amplitude');            
            legend(app.UIAxes_Tscope,'Real','Imag')

            app.bkwfilegenerateButton.Visible = "on";

        end

        % Value changed function: SpatialmappingDropDown
        function Spatialmapping_Custom(app, event)
            Spatialmapping_text = app.SpatialmappingDropDown.Value;

            if strcmp(Spatialmapping_text,'Custom')
                app.BeamformingCheckBox.Visible = 'on';
                app.SpatialmappingmatrixEditField.Visible = 'on';
                app.SpatialmappingmatrixEditFieldLabel.Visible = 'on';
            else
                app.BeamformingCheckBox.Visible = 'off';
                app.BeamformingCheckBox.Value = 0;
                app.SpatialmappingmatrixEditField.Visible = 'off';
                app.SpatialmappingmatrixEditFieldLabel.Visible = 'off';
            end
        end

        % Value changed function: ChannelbandwidthDropDown
        function ChannelbandwidthDropDownValueChanged(app, event)
            CB_text = app.ChannelbandwidthDropDown.Value;
          
            app.MHzLabel.Text = strcat(CB_text(4:end),' MHz');
        end

        % Button pushed function: bkwfilegenerateButton
        function bkwfilegenerate(app, event)
            global Fs;
            global waveform;
            ChannelBandwidth_text = app.ChannelbandwidthDropDown.Value;
            MCS_value = app.MCSDropDown.Value;

            MCS_text = num2str(MCS_value);

            filename = strcat('802.11ac',ChannelBandwidth_text,'_MCS',MCS_text,'.bkw');


            fileID = fopen(filename,'w'); % 파일 열기 (쓰기 모드)

            address_0000_0000 = double('bkwaveformatv0p1');
            fwrite(fileID,address_0000_0000);
            
            % Channel 개수는 뭘 받아야 하는지...
            address_0000_0010 = double('0000............');
            fwrite(fileID,address_0000_0010);
            
            strFs = num2str(Fs);
            RATEstr = '000000000000';
            RATEstr(end-length(strFs)+1:end) = strFs(1:end);
            address_0000_0020 = double(strcat('RATE',RATEstr));
            fwrite(fileID,address_0000_0020);
            
            % LENGHT도 잘...
            strlengthwaveform = num2str(length(waveform));
            LENGTHstr = '0000000000';
            LENGTHstr(end-length(strlengthwaveform)+1:end) = strlengthwaveform(1:end);
            address_0000_0030 = double(strcat('LENGTH',LENGTHstr));
            fwrite(fileID,address_0000_0030);
            
            address_0000_0040 = double('reserved........');
            fwrite(fileID,address_0000_0040);
            
            for i = 1:length(waveform)
                fwrite(fileID, real(waveform(i)), 'double'); % 실수 부분 저장
                fwrite(fileID, imag(waveform(i)), 'double'); % 허수 부분 저장
            end
            fclose(fileID); % 파일 닫기
            
            save = dialog('Position',[300 300 400 100], 'Name','완료');

            txt = uicontrol('Parent',save,...
               'Style','text',...
               'Position',[30 60 350 20],...
               'FontSize',13,...
               'String','bkw 형식으로 waveform을 저장하였습니다.');

            closeBtn = uicontrol('Parent', save, ...
            'Style', 'pushbutton', ...
            'String', 'Close', ...
            'Position', [165 20 70 25], ...
            'Callback', @(src, event) delete(save));

            app.bkwfilegenerateButton.Visible = "off";

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_Spectrum = uiaxes(app.UIFigure);
            title(app.UIAxes_Spectrum, 'Spectrum Analyzer')
            xlabel(app.UIAxes_Spectrum, 'X')
            ylabel(app.UIAxes_Spectrum, 'Y')
            zlabel(app.UIAxes_Spectrum, 'Z')
            app.UIAxes_Tscope = uiaxes(app.UIFigure);
            title(app.UIAxes_Tscope, 'Time Scope')
            xlabel(app.UIAxes_Tscope, 'X')
            ylabel(app.UIAxes_Tscope, 'Y')
            zlabel(app.UIAxes_Tscope, 'Z')
            app.BeamformingCheckBox = uicheckbox(app.UIFigure);
            app.BeamformingCheckBox.Text = 'Beamforming';
            app.GenerateWaveformButton = uibutton(app.UIFigure, 'push');
            app.GenerateWaveformButton.ButtonPushedFcn = createCallbackFcn(app, @waveformGenerate, true);
            app.GenerateWaveformButton.Text = 'Generate Waveform';
            app.WLAN80211acSingleUserVHTconfigurationLabel = uilabel(app.UIFigure);
            app.WLAN80211acSingleUserVHTconfigurationLabel.Text = 'WLAN 802.11ac Single User VHT configuration';
            app.ChannelbandwidthLabel = uilabel(app.UIFigure);
            app.ChannelbandwidthLabel.Text = 'Channel bandwidth:';
            app.ChannelbandwidthDropDown = uidropdown(app.UIFigure);
            app.ChannelbandwidthDropDown.Items = {'20 MHz', '40 MHz', '80 MHz', '160 MHz'};
            app.ChannelbandwidthDropDown.ItemsData = {'CBW20', 'CBW40', 'CBW80', 'CBW160'};
            app.ChannelbandwidthDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelbandwidthDropDownValueChanged, true);
            app.ChannelbandwidthDropDown.Value = 'CBW20';
            app.UsersEditFieldLabel = uilabel(app.UIFigure);
            app.UsersEditFieldLabel.Text = 'Users:';
            app.UsersEditField = uieditfield(app.UIFigure, 'numeric');
            app.UsersEditField.Editable = 'off';
            app.UsersEditField.Value = 1;
            app.SpatialmappingDropDownLabel = uilabel(app.UIFigure);
            app.SpatialmappingDropDownLabel.Text = 'Spatial mapping: ';
            app.SpatialmappingDropDown = uidropdown(app.UIFigure);
            app.SpatialmappingDropDown.Items = {'Direct', 'Hadamard', 'Fourier', 'Custom'};
            app.SpatialmappingDropDown.ValueChangedFcn = createCallbackFcn(app, @Spatialmapping_Custom, true);
            app.SpatialmappingDropDown.Value = 'Direct';
            app.MCSDropDownLabel = uilabel(app.UIFigure);
            app.MCSDropDownLabel.Text = 'MCS: ';
            app.MCSDropDown = uidropdown(app.UIFigure);
            app.MCSDropDown.Items = {'0 (BPSK, 1/2)', '1 (QPSK, 1/2)', '2 (QPSK, 3/4)', '3 (16-QAM, 1/2)', '4 (16-QAM, 3/4)', '5 (64-QAM, 2/3)', '6 (64-QAM, 3/4)', '7 (64-QAM, 5/6)', '8 (256-QAM, 3/4)', '9 (256-QAM, 5/6)'};
            app.MCSDropDown.ItemsData = [0 1 2 3 4 5 6 7 8 9];
            app.MCSDropDown.Value = 0;
            app.ChannelcodingDropDownLabel = uilabel(app.UIFigure);
            app.ChannelcodingDropDownLabel.Text = 'Channel coding: ';
            app.ChannelcodingDropDown = uidropdown(app.UIFigure);
            app.ChannelcodingDropDown.Items = {'BCC', 'LDPC'};
            app.ChannelcodingDropDown.Value = 'BCC';
            app.APEPlengthbytesEditFieldLabel = uilabel(app.UIFigure);
            app.APEPlengthbytesEditFieldLabel.Text = 'APEP length (bytes): ';
            app.APEPlengthbytesEditField = uieditfield(app.UIFigure, 'numeric');
            app.APEPlengthbytesEditField.Limits = [0 1048575];
            app.APEPlengthbytesEditField.Value = 1024;
            app.GuardintervalDropDownLabel = uilabel(app.UIFigure);
            app.GuardintervalDropDownLabel.Text = 'Guard interval: ';
            app.GuardintervalDropDown = uidropdown(app.UIFigure);
            app.GuardintervalDropDown.Items = {'Long', 'Short'};
            app.GuardintervalDropDown.Value = 'Long';
            app.GroupIDEditFieldLabel = uilabel(app.UIFigure);
            app.GroupIDEditFieldLabel.Text = 'Group ID: ';
            app.GroupIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.GroupIDEditField.Limits = [0 63];
            app.GroupIDEditField.Value = 63;
            app.PartialAIDEditFieldLabel = uilabel(app.UIFigure);
            app.PartialAIDEditFieldLabel.Text = 'Partial AID: ';
            app.PartialAIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.PartialAIDEditField.Limits = [0 511];
            app.PartialAIDEditField.Value = 275;
            app.SpatialmappingmatrixEditFieldLabel = uilabel(app.UIFigure);
            app.SpatialmappingmatrixEditFieldLabel.Text = 'Spatial mapping matrix: ';
            app.SpatialmappingmatrixEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpatialmappingmatrixEditField.ValueChangedFcn = createCallbackFcn(app, @waveformGenerate, true);
            app.SpatialmappingmatrixEditField.Value = 1;
            app.PacketsLabel = uilabel(app.UIFigure);
            app.PacketsLabel.Text = 'Packets: ';
            app.PacketsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PacketsEditField.Limits = [1 Inf];
            app.PacketsEditField.ValueChangedFcn = createCallbackFcn(app, @waveformGenerate, true);
            app.PacketsEditField.Value = 1;
            app.IdletimesLabel = uilabel(app.UIFigure);
            app.IdletimesLabel.Text = 'Idle time (s):';
            app.IdletimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.IdletimesEditField.Limits = [0 Inf];
            app.IdletimesEditField.ValueChangedFcn = createCallbackFcn(app, @waveformGenerate, true);
            app.OversamplingfactorEditField_2Label = uilabel(app.UIFigure);
            app.OversamplingfactorEditField_2Label.Text = 'Oversampling factor:';
            app.OversamplingfactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.OversamplingfactorEditField.ValueChangedFcn = createCallbackFcn(app, @waveformGenerate, true);
            app.OversamplingfactorEditField.Value = 1;
            app.ScramblerinitializationLabel = uilabel(app.UIFigure);
            app.ScramblerinitializationLabel.Text = 'Scrambler initialization: ';
            app.ScramblerinitializationEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScramblerinitializationEditField.Limits = [1 Inf];
            app.ScramblerinitializationEditField.ValueChangedFcn = createCallbackFcn(app, @waveformGenerate, true);
            app.ScramblerinitializationEditField.Value = 93;
            app.WindowtransitiontimesEditFieldLabel = uilabel(app.UIFigure);
            app.WindowtransitiontimesEditFieldLabel.Text = 'Window transition time (s):';
            app.WindowtransitiontimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindowtransitiontimesEditField.Limits = [0 1e-06];
            app.WindowtransitiontimesEditField.ValueChangedFcn = createCallbackFcn(app, @waveformGenerate, true);
            app.WindowtransitiontimesEditField.Value = 1e-07;
            app.SamplerateLabel = uilabel(app.UIFigure);
            app.SamplerateLabel.Text = 'Sample rate: ';
            app.MHzLabel = uilabel(app.UIFigure);
            app.MHzLabel.Text = '20MHz';
            app.bkwfilegenerateButton = uibutton(app.UIFigure, 'push');
            app.bkwfilegenerateButton.ButtonPushedFcn = createCallbackFcn(app, @bkwfilegenerate, true);
            app.bkwfilegenerateButton.Text = 'bkw file generate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Subapp_802_11n_ac

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end