classdef ax_waveform_generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TextArea                        matlab.ui.control.TextArea
        TextAreaLabel                   matlab.ui.control.Label
        GeneratorConfigurationLabel     matlab.ui.control.Label
        ExportbkwformatButton           matlab.ui.control.Button
        SampleRateTextArea              matlab.ui.control.TextArea
        SampleRateLabel                 matlab.ui.control.Label
        OversamplingFactorEditField     matlab.ui.control.NumericEditField
        OversamplingFactorEditFieldLabel  matlab.ui.control.Label
        PSDUlengthBytesEditField        matlab.ui.control.NumericEditField
        PSDUlengthBytesEditFieldLabel   matlab.ui.control.Label
        HighDopplerCheckBox             matlab.ui.control.CheckBox
        Upper106ToneRUCheckBox          matlab.ui.control.CheckBox
        GenerateWaveformButton          matlab.ui.control.Button
        ExtendedRangeCheckBox           matlab.ui.control.CheckBox
        PacketpaddingsDropDown          matlab.ui.control.DropDown
        PacketpaddingsDropDownLabel     matlab.ui.control.Label
        TXOPdurationEditField           matlab.ui.control.NumericEditField
        TXOPdurationEditFieldLabel      matlab.ui.control.Label
        SpatialreuseDropDown            matlab.ui.control.DropDown
        SpatialreuseDropDownLabel       matlab.ui.control.Label
        BSScolorEditField               matlab.ui.control.NumericEditField
        BSScolorEditFieldLabel          matlab.ui.control.Label
        UplinkCheckBox                  matlab.ui.control.CheckBox
        HELTFtypeDropDown               matlab.ui.control.DropDown
        HELTFtypeDropDownLabel          matlab.ui.control.Label
        GuardintervalDropDown           matlab.ui.control.DropDown
        GuardintervalDropDownLabel      matlab.ui.control.Label
        APEPlengthbytesEditField        matlab.ui.control.NumericEditField
        APEPlengthbytesEditFieldLabel   matlab.ui.control.Label
        ChannelcodingDropDown           matlab.ui.control.DropDown
        ChannelcodingDropDownLabel      matlab.ui.control.Label
        DCMCheckBox                     matlab.ui.control.CheckBox
        MCSDropDown                     matlab.ui.control.DropDown
        MCSDropDownLabel                matlab.ui.control.Label
        STBCCheckBox                    matlab.ui.control.CheckBox
        PreHESpatialMappingCheckBox     matlab.ui.control.CheckBox
        SpatialmappingDropDown          matlab.ui.control.DropDown
        SpatialmappingDropDownLabel     matlab.ui.control.Label
        SpacetimestreamsDropDown        matlab.ui.control.DropDown
        SpacetimestreamsDropDownLabel   matlab.ui.control.Label
        TransmitantennasEditField       matlab.ui.control.NumericEditField
        TransmitantennasEditFieldLabel  matlab.ui.control.Label
        ChannelbandwidthDropDown        matlab.ui.control.DropDown
        ChannelbandwidthDropDownLabel   matlab.ui.control.Label
        WLAN80211axSingleUserHEconfigurationLabel  matlab.ui.control.Label
        SpectrumAnalyzer                matlab.ui.control.UIAxes
        TimeScope                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global waveform;
            global Fs;

            global numPackets;
            global inbit;
            global tIdle;
            global wintrantime;
            global ScramblerInit;
            global filter;
            
            numPackets=1;
            inbit=randi([0,1],1000,1);
            tIdle=0;
            wintrantime=1e-7;
            ScramblerInit=93;
            filter = dsp.FIRFilter('Numerator', sinc(0.4*(-25:25)) );

            xlabel(app.SpectrumAnalyzer, 'Frequency (Hz)');
            ylabel(app.SpectrumAnalyzer, 'Power (dB)');
            title(app.SpectrumAnalyzer, 'Frequency Spectrum');

            xlabel(app.TimeScope,'TIme(s)');
            ylabel(app.TimeScope,'Amplitude');     
            title(app.TimeScope, 'Time Scope'); 
            app.STBCCheckBox.Value = 0;
            app.STBCCheckBox.Enable = 'off';

        end

        % Value changed function: ChannelbandwidthDropDown, 
        % ...and 1 other component
        function ExtendedRangeCheckBoxValueChanged(app, event)
            value = app.ExtendedRangeCheckBox.Value;
            if value == true
                app.ChannelbandwidthDropDown.Value = 'CBW20';
                app.ChannelbandwidthDropDown.Items = {'20MHz'};
                app.ChannelbandwidthDropDown.Enable = 'off'; % 또는 'inactive'를 사용할 수 있습니다.
                app.Upper106ToneRUCheckBox.Enable = 'on';
                app.SpacetimestreamsDropDown.Value = '1';
            else
                app.ChannelbandwidthDropDown.Items = {'20MHz', '40MHz', '80MHz', '160MHz'};
                app.ChannelbandwidthDropDown.Enable = 'on';
                app.Upper106ToneRUCheckBox.Value = false;
                app.Upper106ToneRUCheckBox.Enable = 'off';
            end
        end

        % Button pushed function: GenerateWaveformButton
        function GenerateWaveformButtonPushed(app, event)
            global waveform;
            global Fs;

            global numPackets;
            global inbit;
            global tIdle;
            global wintrantime;
            global ScramblerInit;
            global filter;
            global CBW;
            global MCS;
            
            ER=app.ExtendedRangeCheckBox.Value;%
            U106TRU=app.Upper106ToneRUCheckBox.Value;%
            CBW=app.ChannelbandwidthDropDown.Value;
            NTA=app.TransmitantennasEditField.Value;
            NSTS=str2num(app.SpacetimestreamsDropDown.Value);
            SM=string(app.SpatialmappingDropDown.Value);
            PHESM=app.PreHESpatialMappingCheckBox.Value;%
            STBC=app.STBCCheckBox.Value;%
            MCS=str2num(app.MCSDropDown.Value);
            DCM=app.DCMCheckBox.Value;%
            CC=app.ChannelcodingDropDown.Value;
            APEPL=app.APEPlengthbytesEditField.Value;
            GI=str2num(app.GuardintervalDropDown.Value);
            HELTFT=str2num(app.HELTFtypeDropDown.Value);
            UI=app.UplinkCheckBox.Value;%
            BSSC=app.BSScolorEditField.Value;
            SR=str2num(app.SpatialreuseDropDown.Value);
            TXOPD=app.TXOPdurationEditField.Value;
            HD=app.HighDopplerCheckBox.Value;%
            NPP=str2num(app.PacketpaddingsDropDown.Value);
            
            heSUCfg=wlanHESUConfig('ExtendedRange', ER, 'Upper106ToneRU', U106TRU, ...
                'ChannelBandwidth', CBW, 'NumTransmitAntennas', NTA, ...
                'NumSpaceTimeStreams', NSTS, 'SpatialMapping', SM, ...
                'PreHESpatialMapping', PHESM, 'STBC', STBC, 'MCS', MCS, ...
                'DCM', DCM, 'ChannelCoding', CC, 'APEPLength', APEPL, ...
                'GuardInterval', GI, 'HELTFType', HELTFT, 'UplinkIndication', UI, ...
                'BSSColor', BSSC, 'SpatialReuse', SR, 'TXOPDuration', TXOPD, ...
                'HighDoppler', HD, 'NominalPacketPadding', NPP);

            waveform = wlanWaveformGenerator(inbit, heSUCfg, ...
                'NumPackets', numPackets, ...
                'IdleTime', tIdle, ...
                'OversamplingFactor', app.OversamplingFactorEditField.Value, ...
                'ScramblerInitialization', ScramblerInit, ...
                'WindowTransitionTime', wintrantime);
            Fs=wlanSampleRate(heSUCfg, 'OversamplingFactor', app.OversamplingFactorEditField.Value);
            
            printFs=[num2str(Fs/1000000),'MHz'];
            app.SampleRateTextArea.Value=printFs;
            %waveform = filter(waveform);

            % App designer 창에 plot하기 위해 freq/time data 생성
            L = length(waveform); % 신호 길이
            f = (-Fs/2):(Fs/L):(Fs/2 - Fs/L); % 주파수 벡터 생성 (-Fs/2부터 Fs/2까지)
            Y = fftshift(fft(waveform)); % FFT 수행 및 주파수 이동
            P = abs(Y/L); 
            P = P(1:length(f));
            
            plot(app.SpectrumAnalyzer, f, 10*log10(P));
            xlabel(app.SpectrumAnalyzer, 'Frequency (Hz)');
            ylabel(app.SpectrumAnalyzer, 'Power (dB)');
            title(app.SpectrumAnalyzer, 'Frequency Spectrum');
           
            t=(0:L-1)*(1/Fs);
            plot(app.TimeScope,t,real(waveform),t,imag(waveform));
            xlabel(app.TimeScope,'TIme(s)');
            ylabel(app.TimeScope,'Amplitude');            
            legend(app.TimeScope,'Real','Imag')
            app.TextArea.Value = app.HELTFtypeDropDown.Value;
        end

        % Drop down opening function: ChannelbandwidthDropDown
        function ChannelbandwidthDropDownOpening(app, event)


        end

        % Drop down opening function: SpatialmappingDropDown
        function SpatialmappingDropDownOpening(app, event)
            if string(app.TransmitantennasEditField.Value) ~= string(app.SpacetimestreamsDropDown.Value)
                app.SpatialmappingDropDown.Items = {'Hadamard', 'Fourier', 'Custom'};
            else
                app.SpatialmappingDropDown.Items = {'Direct', 'Hadamard', 'Fourier', 'Custom'};
            end
            

        end

        % Value changed function: SpacetimestreamsDropDown
        function SpacetimestreamsDropDownValueChanged(app, event)
           

            if (string(app.SpacetimestreamsDropDown.Value) ~= string(2)) || (string(app.DCMCheckBox.Value) ~= string(false))
                app.STBCCheckBox.Value = false;
                app.STBCCheckBox.Enable = 'off';
            else
                app.STBCCheckBox.Enable = 'on';
            end
            NSTS=str2double(app.SpacetimestreamsDropDown.Value);
            MCS=str2double(app.MCSDropDown.Value);
            if (MCS <= 4) && (MCS ~= 2) && (app.STBCCheckBox.Value == false) && (NSTS <= 2)
                app.DCMCheckBox.Enable = 'on';
            else
                app.DCMCheckBox.Value = false;
                app.DCMCheckBox.Enable = 'off';
            end
            if NSTS > 4
                app.ChannelcodingDropDown.Value = 'LDPC';
                app.ChannelcodingDropDown.Enable = 'off';
                app.HighDopplerCheckBox.Value = false;
                app.HighDopplerCheckBox.Enable = 'off';
                
            else
                app.ChannelcodingDropDown.Enable = 'on';
                app.HighDopplerCheckBox.Enable = 'on';

            end
            if app.TransmitantennasEditField.Value ~= NSTS
                app.SpatialmappingDropDown.Items = {'Hadamard', 'Fourier', 'Custom'};
            else
                app.SpatialmappingDropDown.Items = {'Direct', 'Hadamard', 'Fourier', 'Custom'};
            end
            app.TransmitantennasEditField.Limits=[str2double(app.SpacetimestreamsDropDown.Value) Inf];
            
        end

        % Value changed function: DCMCheckBox
        function DCMCheckBoxValueChanged(app, event)
            if (string(app.SpacetimestreamsDropDown.Value) ~= string(2)) || (string(app.DCMCheckBox.Value) ~= string(false))
                app.STBCCheckBox.Value = false;
                app.STBCCheckBox.Enable = 'off';
            else
                app.STBCCheckBox.Enable ='on';
            end
            
        end

        % Drop down opening function: MCSDropDown
        function MCSDropDownOpening(app, event)
            if string(app.Upper106ToneRUCheckBox.Value) == string(true)
                app.MCSDropDown.Items = {'0 (BPSK, 1/2 rate)'};
            elseif string(app.ExtendedRangeCheckBox.Value) == string(true)
                app.MCSDropDown.Items = {'0 (BPSK, 1/2 rate)', '1 (QPSK, 1/2 rate)', '2 (QPSK, 3/4 rate)'};
            end
        end

        % Value changed function: MCSDropDown
        function MCSDropDownValueChanged(app, event)
            NSTS=str2double(app.SpacetimestreamsDropDown.Value);
            MCS=str2double(app.MCSDropDown.Value);
            if (MCS <= 4) && (MCS ~= 2) && (app.STBCCheckBox.Value == false) && (NSTS <= 2)
                app.ChannelcodingDropDown.Enable = 'on';
                app.DCMCheckBox.Enable = 'on';
                
            elseif (MCS > 9)
                app.ChannelcodingDropDown.Value = 'LDPC';
                app.ChannelcodingDropDown.Enable = 'off';
                app.DCMCheckBox.Value = false;
                app.DCMCheckBox.Enable = 'off';
            else
                app.ChannelcodingDropDown.Enable = 'on';
                app.DCMCheckBox.Value = false;
                app.DCMCheckBox.Enable = 'off';
            end
            
        end

        % Value changed function: STBCCheckBox
        function STBCCheckBoxValueChanged(app, event)
            NSTS=str2num(app.SpacetimestreamsDropDown.Value);
            MCS=str2num(app.MCSDropDown.Value);
            if (MCS <= 4) && (MCS ~= 2) && (app.STBCCheckBox.Value == false) && (NSTS <= 2)
                app.DCMCheckBox.Enable = 'on';
            else
                app.DCMCheckBox.Value = false;
                app.DCMCheckBox.Enable = 'off';
            end
            
        end

        % Drop down opening function: ChannelcodingDropDown
        function ChannelcodingDropDownOpening(app, event)
            NSTS=str2num(app.SpacetimestreamsDropDown.Value);
            MCS=str2num(app.MCSDropDown.Value);
            if (MCS < 10) && (NSTS <= 4) % && (runInfo(wlanHESUConfig) <= 242) RU of Single user is always 242 
                app.ChannelcodingDropDown.Items = {'LDPC', 'BCC'};
            else
                app.ChannelcodingDropDown.Items = {'LDPC'};
            end
        end

        % Value changed function: ChannelcodingDropDown
        function ChannelcodingDropDownValueChanged(app, event)

        end

        % Drop down opening function: SpacetimestreamsDropDown
        function SpacetimestreamsDropDownOpening(app, event)

            if app.ExtendedRangeCheckBox.Value == 1
                app.SpacetimestreamsDropDown.Items = {'1','2'};
                app.SpacetimestreamsDropDown.Value = '1';
                if app.TransmitantennasEditField.Value == 1
                    app.SpacetimestreamsDropDown.Items = {'1'};
                elseif app.TransmitantennasEditField.Value == 2
                    app.SpacetimestreamsDropDown.Items = {'1','2'};
                end
            elseif app.ExtendedRangeCheckBox.Value == 0
                if app.TransmitantennasEditField.Value == 1
                    app.SpacetimestreamsDropDown.Items = {'1'};
                elseif app.TransmitantennasEditField.Value == 2
                    app.SpacetimestreamsDropDown.Items = {'1','2'};
                elseif app.TransmitantennasEditField.Value == 3
                    app.SpacetimestreamsDropDown.Items = {'1','2','3'};
                elseif app.TransmitantennasEditField.Value == 4
                    app.SpacetimestreamsDropDown.Items = {'1','2','3','4'};
                elseif app.TransmitantennasEditField.Value == 5
                    app.SpacetimestreamsDropDown.Items = {'1','2','3','4','5'};
                elseif app.TransmitantennasEditField.Value == 6
                    app.SpacetimestreamsDropDown.Items = {'1','2','3','4','5','6'};
                elseif app.TransmitantennasEditField.Value == 7
                    app.SpacetimestreamsDropDown.Items = {'1','2','3','4','5','6','7'};
                else
                    app.SpacetimestreamsDropDown.Items = {'1','2','3','4','5','6','7','8'};
                end               
            end
            

        end

        % Value changed function: APEPlengthbytesEditField
        function APEPlengthbytesEditFieldValueChanged(app, event)
            if app.APEPlengthbytesEditField.Value == 0
                app.PacketpaddingsDropDown.Value = '0';
            else
            end
        end

        % Value changed function: TransmitantennasEditField
        function TransmitantennasEditFieldValueChanged(app, event)
            NSTS=str2double(app.SpacetimestreamsDropDown.Value);
            if app.TransmitantennasEditField.Value ~= NSTS
                app.SpatialmappingDropDown.Items = {'Hadamard', 'Fourier', 'Custom'};
            else
                app.SpatialmappingDropDown.Items = {'Direct', 'Hadamard', 'Fourier', 'Custom'};
            end
            
        end

        % Drop down opening function: PacketpaddingsDropDown
        function PacketpaddingsDropDownOpening(app, event)
            if (app.APEPlengthbytesEditField.Value >= 1) && (app.APEPlengthbytesEditField.Value <=6500531)
                app.PacketpaddingsDropDown.Items = {'0', '8', '16'};
            else
                app.PacketpaddingsDropDown.Items = {'0'};      
            end
        end

        % Button pushed function: ExportbkwformatButton
        function ExportbkwformatButtonPushed(app, event)
            global waveform;
            global Fs;
            global CBW;
            global MCS;

            ChannelBandwidth_text = app.ChannelbandwidthDropDown.Value;
            
            % MCS값 불러오기
            MCS_value = MCS;
            MCS_text = num2str(MCS_value);
            
            % bkw 파일 이름 생성하기
            filename = strcat('802.11ax',ChannelBandwidth_text,'_MCS',MCS_text,'.bkw');
            
            % bkw 파일 열기 (쓰기 모드)
            fileID = fopen(filename,'w');
            
            % 0000_0000 입력
            address_0000_0000 = double('bkwaveformatv0p1');
            fwrite(fileID,address_0000_0000);
                        
            % 0000_0010 입력
            address_0000_0010 = double('0000............');
            fwrite(fileID,address_0000_0010);
            
            % 0000_0020 입력
            strFs = num2str(Fs);
            RATEstr = '000000000000';
            RATEstr(end-length(strFs)+1:end) = strFs(1:end);
            address_0000_0020 = double(strcat('RATE',RATEstr));
            fwrite(fileID,address_0000_0020);
            
            % 0000_0030 입력
            strlengthwaveform = num2str(length(waveform));
            LENGTHstr = '0000000000';
            LENGTHstr(end-length(strlengthwaveform)+1:end) = strlengthwaveform(1:end);
            address_0000_0030 = double(strcat('LENGTH',LENGTHstr));
            fwrite(fileID,address_0000_0030);
            
            % 0000_0040 입력
            address_0000_0040 = double('reserved........');
            fwrite(fileID,address_0000_0040);
            
            % waveform data 저장
            for i = 1:length(waveform)
                fwrite(fileID, real(waveform(i)), 'double'); % 실수 부분 저장
                fwrite(fileID, imag(waveform(i)), 'double'); % 허수 부분 저장
            end

            % 파일 닫기
            fclose(fileID);
        end

        % Value changed function: GuardintervalDropDown
        function GuardintervalDropDownValueChanged(app, event)
            value = str2num(app.GuardintervalDropDown.Value);
            if (value == 3.2)
                app.HELTFtypeDropDown.Items={'4'};
                app.HELTFtypeDropDown.Value=string(4);
            elseif (value == 1.6)
                app.HELTFtypeDropDown.Items={'2'};
                app.HELTFtypeDropDown.Value=string(2);
                %help에선 gi가 1.6일 때 HELTF 4가 불가인데 실제로 1도 불가
            else
                app.HELTFtypeDropDown.Items={'4','2','1'};
            end
        end

        % Drop down opening function: HELTFtypeDropDown
        function HELTFtypeDropDownOpening(app, event)
            if str2num(app.GuardintervalDropDown.Value) == 3.2
                app.HELTFtypeDropDown.Items={'4'};
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimeScope = uiaxes(app.UIFigure);
            title(app.TimeScope, 'Time Scope')
            xlabel(app.TimeScope, 'X')
            ylabel(app.TimeScope, 'Y')
            zlabel(app.TimeScope, 'Z')
            app.SpectrumAnalyzer = uiaxes(app.UIFigure);
            title(app.SpectrumAnalyzer, 'Spectrum Analyzer')
            xlabel(app.SpectrumAnalyzer, 'X')
            ylabel(app.SpectrumAnalyzer, 'Y')
            zlabel(app.SpectrumAnalyzer, 'Z')
            app.WLAN80211axSingleUserHEconfigurationLabel = uilabel(app.UIFigure);
            app.WLAN80211axSingleUserHEconfigurationLabel.Text = 'WLAN 802.11ax Single User HE configuration';
            app.ChannelbandwidthDropDownLabel = uilabel(app.UIFigure);
            app.ChannelbandwidthDropDownLabel.Text = 'Channel bandwidth';
            app.ChannelbandwidthDropDown = uidropdown(app.UIFigure);
            app.ChannelbandwidthDropDown.Items = {'20MHz', '40MHz', '80MHz', '160MHz'};
            app.ChannelbandwidthDropDown.ItemsData = {'CBW20', 'CBW40', 'CBW80', 'CBW160'};
            app.ChannelbandwidthDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ChannelbandwidthDropDownOpening, true);
            app.ChannelbandwidthDropDown.ValueChangedFcn = createCallbackFcn(app, @ExtendedRangeCheckBoxValueChanged, true);
            app.ChannelbandwidthDropDown.Value = 'CBW20';
            app.TransmitantennasEditFieldLabel = uilabel(app.UIFigure);
            app.TransmitantennasEditFieldLabel.Text = 'Transmit antennas';
            app.TransmitantennasEditField = uieditfield(app.UIFigure, 'numeric');
            app.TransmitantennasEditField.Limits = [1 Inf];
            app.TransmitantennasEditField.ValueChangedFcn = createCallbackFcn(app, @TransmitantennasEditFieldValueChanged, true);
            app.TransmitantennasEditField.Value = 1;
            app.SpacetimestreamsDropDownLabel = uilabel(app.UIFigure);
            app.SpacetimestreamsDropDownLabel.Text = 'Space-time streams';
            app.SpacetimestreamsDropDown = uidropdown(app.UIFigure);
            app.SpacetimestreamsDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.SpacetimestreamsDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.SpacetimestreamsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SpacetimestreamsDropDownOpening, true);
            app.SpacetimestreamsDropDown.ValueChangedFcn = createCallbackFcn(app, @SpacetimestreamsDropDownValueChanged, true);
            app.SpacetimestreamsDropDown.Value = '1';
            app.SpatialmappingDropDownLabel = uilabel(app.UIFigure);
            app.SpatialmappingDropDownLabel.Text = 'Spatial mapping';
            app.SpatialmappingDropDown = uidropdown(app.UIFigure);
            app.SpatialmappingDropDown.Items = {'Direct', 'Hadamard', 'Fourier', 'Custom'};
            app.SpatialmappingDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SpatialmappingDropDownOpening, true);
            app.SpatialmappingDropDown.Value = 'Hadamard';
            app.PreHESpatialMappingCheckBox = uicheckbox(app.UIFigure);
            app.PreHESpatialMappingCheckBox.Text = 'Pre-HE Spatial Mapping';
            app.STBCCheckBox = uicheckbox(app.UIFigure);
            app.STBCCheckBox.ValueChangedFcn = createCallbackFcn(app, @STBCCheckBoxValueChanged, true);
            app.STBCCheckBox.Text = 'STBC';
            app.MCSDropDownLabel = uilabel(app.UIFigure);
            app.MCSDropDownLabel.Text = 'MCS';
            app.MCSDropDown = uidropdown(app.UIFigure);
            app.MCSDropDown.Items = {'0 (BPSK, 1/2 rate)', '1 (QPSK, 1/2 rate)', '2 (QPSK, 3/4 rate)', '3 (16-QAM, 1/2 rate)', '4 (16-QAM, 3/4 rate)', '5 (64-QAM, 2/3 rate)', '6 (64-QAM, 3/4 rate)', '7 (64-QAM, 5/6 rate)', '8 (256-QAM, 3/4 rate)', '9 (256-QAM, 5/6 rate)', '10 (1024-QAM, 3/4 rate)', '11 (1024-QAM, 5/6 rate)'};
            app.MCSDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.MCSDropDown.DropDownOpeningFcn = createCallbackFcn(app, @MCSDropDownOpening, true);
            app.MCSDropDown.ValueChangedFcn = createCallbackFcn(app, @MCSDropDownValueChanged, true);
            app.MCSDropDown.Value = '0';
            app.DCMCheckBox = uicheckbox(app.UIFigure);
            app.DCMCheckBox.ValueChangedFcn = createCallbackFcn(app, @DCMCheckBoxValueChanged, true);
            app.DCMCheckBox.Text = 'DCM';
            app.ChannelcodingDropDownLabel = uilabel(app.UIFigure);
            app.ChannelcodingDropDownLabel.Text = 'Channel coding';
            app.ChannelcodingDropDown = uidropdown(app.UIFigure);
            app.ChannelcodingDropDown.Items = {'LDPC', 'BCC'};
            app.ChannelcodingDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ChannelcodingDropDownOpening, true);
            app.ChannelcodingDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelcodingDropDownValueChanged, true);
            app.ChannelcodingDropDown.Value = 'LDPC';
            app.APEPlengthbytesEditFieldLabel = uilabel(app.UIFigure);
            app.APEPlengthbytesEditFieldLabel.Text = 'APEP length (bytes)';
            app.APEPlengthbytesEditField = uieditfield(app.UIFigure, 'numeric');
            app.APEPlengthbytesEditField.Limits = [0 6451631];
            app.APEPlengthbytesEditField.ValueChangedFcn = createCallbackFcn(app, @APEPlengthbytesEditFieldValueChanged, true);
            app.APEPlengthbytesEditField.Value = 100;
            app.GuardintervalDropDownLabel = uilabel(app.UIFigure);
            app.GuardintervalDropDownLabel.Text = 'Guard interval';
            app.GuardintervalDropDown = uidropdown(app.UIFigure);
            app.GuardintervalDropDown.Items = {'3.2μs ', '1.6μs', '0.8μs'};
            app.GuardintervalDropDown.ItemsData = {'3.2', '1.6', '0.8'};
            app.GuardintervalDropDown.ValueChangedFcn = createCallbackFcn(app, @GuardintervalDropDownValueChanged, true);
            app.GuardintervalDropDown.Value = '3.2';
            app.HELTFtypeDropDownLabel = uilabel(app.UIFigure);
            app.HELTFtypeDropDownLabel.Text = 'HE-LTF type';
            app.HELTFtypeDropDown = uidropdown(app.UIFigure);
            app.HELTFtypeDropDown.Items = {'4', '2', '1'};
            app.HELTFtypeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @HELTFtypeDropDownOpening, true);
            app.HELTFtypeDropDown.Value = '4';
            app.UplinkCheckBox = uicheckbox(app.UIFigure);
            app.UplinkCheckBox.Text = 'Uplink';
            app.BSScolorEditFieldLabel = uilabel(app.UIFigure);
            app.BSScolorEditFieldLabel.Text = 'BSS color';
            app.BSScolorEditField = uieditfield(app.UIFigure, 'numeric');
            app.BSScolorEditField.Limits = [0 63];
            app.SpatialreuseDropDownLabel = uilabel(app.UIFigure);
            app.SpatialreuseDropDownLabel.Text = 'Spatial  reuse';
            app.SpatialreuseDropDown = uidropdown(app.UIFigure);
            app.SpatialreuseDropDown.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.SpatialreuseDropDown.Value = '0';
            app.TXOPdurationEditFieldLabel = uilabel(app.UIFigure);
            app.TXOPdurationEditFieldLabel.Text = 'TXOP duration';
            app.TXOPdurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.TXOPdurationEditField.Limits = [0 127];
            app.TXOPdurationEditField.Value = 127;
            app.PacketpaddingsDropDownLabel = uilabel(app.UIFigure);
            app.PacketpaddingsDropDownLabel.Text = 'Packet padding (μs)';
            app.PacketpaddingsDropDown = uidropdown(app.UIFigure);
            app.PacketpaddingsDropDown.Items = {'0', '8', '16'};
            app.PacketpaddingsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PacketpaddingsDropDownOpening, true);
            app.PacketpaddingsDropDown.Value = '0';
            app.ExtendedRangeCheckBox = uicheckbox(app.UIFigure);
            app.ExtendedRangeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExtendedRangeCheckBoxValueChanged, true);
            app.ExtendedRangeCheckBox.Text = 'Extended Range';
            app.GenerateWaveformButton = uibutton(app.UIFigure, 'push');
            app.GenerateWaveformButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateWaveformButtonPushed, true);
            app.GenerateWaveformButton.Text = 'Generate Waveform';
            app.Upper106ToneRUCheckBox = uicheckbox(app.UIFigure);
            app.Upper106ToneRUCheckBox.Text = 'Upper106ToneRU';
            app.HighDopplerCheckBox = uicheckbox(app.UIFigure);
            app.HighDopplerCheckBox.Text = 'High Doppler';
            app.PSDUlengthBytesEditFieldLabel = uilabel(app.UIFigure);
            app.PSDUlengthBytesEditFieldLabel.Text = 'PSDU length(Bytes)';
            app.PSDUlengthBytesEditField = uieditfield(app.UIFigure, 'numeric');
            app.PSDUlengthBytesEditField.Value = 100;
            app.OversamplingFactorEditFieldLabel = uilabel(app.UIFigure);
            app.OversamplingFactorEditFieldLabel.Text = 'Oversampling Factor';
            app.OversamplingFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.OversamplingFactorEditField.Limits = [0 100];
            app.OversamplingFactorEditField.Value = 1;
            app.SampleRateLabel = uilabel(app.UIFigure);
            app.SampleRateLabel.Text = 'SampleRate';
            app.SampleRateTextArea = uitextarea(app.UIFigure);
            app.ExportbkwformatButton = uibutton(app.UIFigure, 'push');
            app.ExportbkwformatButton.ButtonPushedFcn = createCallbackFcn(app, @ExportbkwformatButtonPushed, true);
            app.ExportbkwformatButton.Text = 'Export(.bkw format)';
            app.GeneratorConfigurationLabel = uilabel(app.UIFigure);
            app.GeneratorConfigurationLabel.Text = 'Generator Configuration';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ax_waveform_generator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end