classdef be_waveform_generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TextArea                        matlab.ui.control.TextArea
        TextAreaLabel                   matlab.ui.control.Label
        TXOPdurationEditField           matlab.ui.control.NumericEditField
        TXOPdurationEditFieldLabel      matlab.ui.control.Label
        BSScolorEditField               matlab.ui.control.NumericEditField
        BSScolorEditFieldLabel          matlab.ui.control.Label
        NumExtraEHTLTFsymbolsDropDown   matlab.ui.control.DropDown
        NumExtraEHTLTFsymbolsDropDownLabel  matlab.ui.control.Label
        LDPCextrasymbolCheckBox         matlab.ui.control.CheckBox
        PreFECpaddingfactorEditField    matlab.ui.control.NumericEditField
        PreFECpaddingfactorEditFieldLabel  matlab.ui.control.Label
        ChannelcodingDropDown           matlab.ui.control.DropDown
        ChannelcodingDropDownLabel      matlab.ui.control.Label
        MCSDropDown                     matlab.ui.control.DropDown
        MCSDropDownLabel                matlab.ui.control.Label
        SpatialmappingDropDown          matlab.ui.control.DropDown
        SpatialmappingDropDownLabel     matlab.ui.control.Label
        SpacetimestreamsEditField       matlab.ui.control.NumericEditField
        SpacetimestreamsEditFieldLabel  matlab.ui.control.Label
        TransmitantennasEditField       matlab.ui.control.NumericEditField
        TransmitantennasEditFieldLabel  matlab.ui.control.Label
        PreEHTpowerscalingfactorEditField  matlab.ui.control.NumericEditField
        PreEHTpowerscalingfactorEditFieldLabel  matlab.ui.control.Label
        EHTLTFtypeDropDown              matlab.ui.control.DropDown
        EHTLTFtypeDropDownLabel         matlab.ui.control.Label
        GuardintervalDropDown           matlab.ui.control.DropDown
        GuardintervalDropDownLabel      matlab.ui.control.Label
        ChannelbandwidthDropDown        matlab.ui.control.DropDown
        ChannelbandwidthDropDownLabel   matlab.ui.control.Label
        WLAN80211beSingleUserEHTTBconfigurationLabel  matlab.ui.control.Label
        SampleRateTextArea              matlab.ui.control.TextArea
        SampleRateTextAreaLabel         matlab.ui.control.Label
        OversamplingFactorEditField     matlab.ui.control.NumericEditField
        OversamplingFactorEditFieldLabel  matlab.ui.control.Label
        PSDUlengthBytesEditField        matlab.ui.control.NumericEditField
        PSDUlengthBytesEditFieldLabel   matlab.ui.control.Label
        GeneratorConfigurationLabel     matlab.ui.control.Label
        ExportbkwformatButton           matlab.ui.control.Button
        GenerateWaveformButton          matlab.ui.control.Button
        SpectrumAnalyzer                matlab.ui.control.UIAxes
        TimeScope                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global waveform;
            global Fs;

            global numPackets;
            global inbit;
            global tIdle;
            global wintrantime;
            global ScramblerInit;
            global filter;
            
            numPackets=1;
            inbit=randi([0,1],1000,1);
            tIdle=0;
            wintrantime=1e-7;
            ScramblerInit=93;
            filter = dsp.FIRFilter('Numerator', sinc(0.4*(-25:25)) );

            xlabel(app.SpectrumAnalyzer, 'Frequency (Hz)');
            ylabel(app.SpectrumAnalyzer, 'Power (dB)');
            title(app.SpectrumAnalyzer, 'Frequency Spectrum');

            xlabel(app.TimeScope,'TIme(s)');
            ylabel(app.TimeScope,'Amplitude');     
            title(app.TimeScope, 'Time Scope'); 

        end

        % Button pushed function: GenerateWaveformButton
        function GenerateWaveformButtonPushed(app, event)
            global waveform;
            global Fs;

            global numPackets;
            global inbit;
            global tIdle;
            global wintrantime;
            global ScramblerInit;
            global filter;
            global CBW;
            global MCS;
            
            
            CBW=string(app.ChannelbandwidthDropDown.Value);
            PEHT=app.PreEHTpowerscalingfactorEditField.Value;
            SM=string(app.SpatialmappingDropDown.Value);  
            MCS=str2double(app.MCSDropDown.Value);
            CC=string(app.ChannelcodingDropDown.Value);
            LES=app.LDPCextrasymbolCheckBox.Value;
            PFPF=app.PreFECpaddingfactorEditField.Value;
            GI=str2double(app.GuardintervalDropDown.Value);
            EHTLTFT=str2num(app.EHTLTFtypeDropDown.Value);   
            BSSC=app.BSScolorEditField.Value;
            TXOPD=app.TXOPdurationEditField.Value;

            
            EHTTBConfig=wlanEHTTBConfig('ChannelBandwidth', CBW, 'SpatialMapping', SM, ...
                'PreEHTPowerScalingFactor', PEHT, 'MCS', MCS, 'ChannelCoding', CC, ...
                'LDPCExtraSymbol', LES,'PreFECPaddingFactor', PFPF, 'GuardInterval', GI, 'EHTLTFType', EHTLTFT, ...
                'BSSColor', BSSC, 'TXOPDuration', TXOPD);

            waveform = wlanWaveformGenerator(inbit, EHTTBConfig, ...
                'NumPackets', numPackets, ...
                'IdleTime', tIdle, ...
                'OversamplingFactor', app.OversamplingFactorEditField.Value, ...
                'ScramblerInitialization', ScramblerInit, ...
                'WindowTransitionTime', wintrantime);
            Fs=wlanSampleRate(EHTTBConfig, 'OversamplingFactor', app.OversamplingFactorEditField.Value);
            
            printFs=[num2str(Fs/1000000),'MHz'];
            app.SampleRateTextArea.Value=printFs;
            %waveform = filter(waveform);

            % App designer 창에 plot하기 위해 freq/time data 생성
            L = length(waveform); % 신호 길이
            f = (-Fs/2):(Fs/L):(Fs/2 - Fs/L); % 주파수 벡터 생성 (-Fs/2부터 Fs/2까지)
            Y = fftshift(fft(waveform)); % FFT 수행 및 주파수 이동
            P = abs(Y/L); 
            P = P(1:length(f));
            
            plot(app.SpectrumAnalyzer, f, 10*log10(P));
            xlabel(app.SpectrumAnalyzer, 'Frequency (Hz)');
            ylabel(app.SpectrumAnalyzer, 'Power (dB)');
            title(app.SpectrumAnalyzer, 'Frequency Spectrum');
           
            t=(0:L-1)*(1/Fs);
            plot(app.TimeScope,t,real(waveform),t,imag(waveform));
            xlabel(app.TimeScope,'TIme(s)');
            ylabel(app.TimeScope,'Amplitude');            
            legend(app.TimeScope,'Real','Imag')
            app.TextArea.Value=string(str2num(app.MCSDropDown.Value));
        end

        % Value changed function: ChannelcodingDropDown
        function ChannelcodingDropDownValueChanged(app, event)
            value = string(app.ChannelcodingDropDown.Value);
            if value == 'LDPC'
                app.LDPCextrasymbolCheckBox.Value = 0;
                app.LDPCextrasymbolCheckBox.Enable = 'on';
            else
                app.LDPCextrasymbolCheckBox.Value = 0;
                app.LDPCextrasymbolCheckBox.Enable = 'off';
            end

        end

        % Button pushed function: ExportbkwformatButton
        function ExportbkwformatButtonPushed(app, event)
            global waveform;
            global Fs;
            global CBW;
            global MCS;

            ChannelBandwidth_text = app.ChannelbandwidthDropDown.Value;
            
            % MCS값 불러오기
            MCS_value = MCS;
            MCS_text = num2str(MCS_value);
            
            % bkw 파일 이름 생성하기
            filename = strcat('802.11be',ChannelBandwidth_text,'_MCS',MCS_text,'.bkw');
            
            % bkw 파일 열기 (쓰기 모드)
            fileID = fopen(filename,'w');
            
            % 0000_0000 입력
            address_0000_0000 = double('bkwaveformatv0p1');
            fwrite(fileID,address_0000_0000);
                        
            % 0000_0010 입력
            address_0000_0010 = double('0000............');
            fwrite(fileID,address_0000_0010);
            
            % 0000_0020 입력
            strFs = num2str(Fs);
            RATEstr = '000000000000';
            RATEstr(end-length(strFs)+1:end) = strFs(1:end);
            address_0000_0020 = double(strcat('RATE',RATEstr));
            fwrite(fileID,address_0000_0020);
            
            % 0000_0030 입력
            strlengthwaveform = num2str(length(waveform));
            LENGTHstr = '0000000000';
            LENGTHstr(end-length(strlengthwaveform)+1:end) = strlengthwaveform(1:end);
            address_0000_0030 = double(strcat('LENGTH',LENGTHstr));
            fwrite(fileID,address_0000_0030);
            
            % 0000_0040 입력
            address_0000_0040 = double('reserved........');
            fwrite(fileID,address_0000_0040);
            
            % waveform data 저장
            for i = 1:length(waveform)
                fwrite(fileID, real(waveform(i)), 'double'); % 실수 부분 저장
                fwrite(fileID, imag(waveform(i)), 'double'); % 허수 부분 저장
            end

            % 파일 닫기
            fclose(fileID);
        end

        % Drop down opening function: GuardintervalDropDown
        function GuardintervalDropDownOpening(app, event)
       
        end

        % Drop down opening function: EHTLTFtypeDropDown
        function EHTLTFtypeDropDownOpening(app, event)
            if string(app.GuardintervalDropDown.Value) == '3.2'
                app.EHTLTFtypeDropDown.Value = '4';
                app.EHTLTFtypeDropDown.Items = {'4'};
            elseif string(app.GuardintervalDropDown.Value) == '1.6'
                app.EHTLTFtypeDropDown.Items = {'2', '1'};
                app.EHTLTFtypeDropDown.Value = '2';
            end
        end

        % Value changed function: MCSDropDown
        function MCSDropDownValueChanged(app, event)
    
            if (str2num(app.MCSDropDown.Value) >=10)&&(str2num(app.MCSDropDown.Value)<=13)
                app.ChannelcodingDropDown.Value = 'LDPC';
                app.ChannelcodingDropDown.Items = {'LDPC'};
            else
                app.ChannelcodingDropDown.Items = {'LDPC', 'BCC'};
            end
        end

        % Drop down opening function: MCSDropDown
        function MCSDropDownOpening(app, event)
   
        end

        % Value changed function: GuardintervalDropDown
        function GuardintervalDropDownValueChanged(app, event)

            if string(app.GuardintervalDropDown.Value) == '1.6'
                app.EHTLTFtypeDropDown.Items = {'2', '1'};
                app.EHTLTFtypeDropDown.Value = '2';
            else 
                app.EHTLTFtypeDropDown.Items = {'4'};
                app.EHTLTFtypeDropDown.Value = '4';
            end

             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimeScope = uiaxes(app.UIFigure);
            title(app.TimeScope, 'Time Scope')
            xlabel(app.TimeScope, 'X')
            ylabel(app.TimeScope, 'Y')
            zlabel(app.TimeScope, 'Z')
            app.SpectrumAnalyzer = uiaxes(app.UIFigure);
            title(app.SpectrumAnalyzer, 'Spectrum Analyzer')
            xlabel(app.SpectrumAnalyzer, 'X')
            ylabel(app.SpectrumAnalyzer, 'Y')
            zlabel(app.SpectrumAnalyzer, 'Z')
            app.GenerateWaveformButton = uibutton(app.UIFigure, 'push');
            app.GenerateWaveformButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateWaveformButtonPushed, true);
            app.GenerateWaveformButton.Text = 'Generate Waveform';
            app.ExportbkwformatButton = uibutton(app.UIFigure, 'push');
            app.ExportbkwformatButton.ButtonPushedFcn = createCallbackFcn(app, @ExportbkwformatButtonPushed, true);
            app.ExportbkwformatButton.Text = 'Export(.bkw format)';
            app.GeneratorConfigurationLabel = uilabel(app.UIFigure);
            app.GeneratorConfigurationLabel.Text = 'Generator Configuration';
            app.PSDUlengthBytesEditFieldLabel = uilabel(app.UIFigure);
            app.PSDUlengthBytesEditFieldLabel.Text = 'PSDU length(Bytes)';
            app.PSDUlengthBytesEditField = uieditfield(app.UIFigure, 'numeric');
            app.PSDUlengthBytesEditField.Value = 100;
            app.OversamplingFactorEditFieldLabel = uilabel(app.UIFigure);
            app.OversamplingFactorEditFieldLabel.Text = 'Oversampling Factor';
            app.OversamplingFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.OversamplingFactorEditField.Limits = [0 100];
            app.OversamplingFactorEditField.Value = 1;
            app.SampleRateTextAreaLabel = uilabel(app.UIFigure);
            app.SampleRateTextAreaLabel.Text = 'SampleRate';
            app.SampleRateTextArea = uitextarea(app.UIFigure);
            app.WLAN80211beSingleUserEHTTBconfigurationLabel = uilabel(app.UIFigure);
            app.WLAN80211beSingleUserEHTTBconfigurationLabel.Text = 'WLAN 802.11be Single User EHTTB configuration';
            app.ChannelbandwidthDropDownLabel = uilabel(app.UIFigure);
            app.ChannelbandwidthDropDownLabel.Text = 'Channel bandwidth';
            app.ChannelbandwidthDropDown = uidropdown(app.UIFigure);
            app.ChannelbandwidthDropDown.Items = {'20MHz', '40MHz', '80MHz', '160MHz', '320MHz'};
            app.ChannelbandwidthDropDown.ItemsData = {'CBW20', 'CBW40', 'CBW80', 'CBW160', 'CBW320'};
            app.ChannelbandwidthDropDown.Value = 'CBW20';
            app.GuardintervalDropDownLabel = uilabel(app.UIFigure);
            app.GuardintervalDropDownLabel.Text = 'Guard interval';
            app.GuardintervalDropDown = uidropdown(app.UIFigure);
            app.GuardintervalDropDown.Items = {'3.2μs ', '1.6μs'};
            app.GuardintervalDropDown.ItemsData = {'3.2', '1.6'};
            app.GuardintervalDropDown.DropDownOpeningFcn = createCallbackFcn(app, @GuardintervalDropDownOpening, true);
            app.GuardintervalDropDown.ValueChangedFcn = createCallbackFcn(app, @GuardintervalDropDownValueChanged, true);
            app.GuardintervalDropDown.Value = '3.2';
            app.EHTLTFtypeDropDownLabel = uilabel(app.UIFigure);
            app.EHTLTFtypeDropDownLabel.Text = 'EHT-LTF type';
            app.EHTLTFtypeDropDown = uidropdown(app.UIFigure);
            app.EHTLTFtypeDropDown.Items = {'4', '2', '1'};
            app.EHTLTFtypeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @EHTLTFtypeDropDownOpening, true);
            app.EHTLTFtypeDropDown.Value = '4';
            app.PreEHTpowerscalingfactorEditFieldLabel = uilabel(app.UIFigure);
            app.PreEHTpowerscalingfactorEditFieldLabel.Text = 'Pre EHT power scaling factor';
            app.PreEHTpowerscalingfactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.PreEHTpowerscalingfactorEditField.Limits = [0.707106781186547 1];
            app.PreEHTpowerscalingfactorEditField.Value = 1;
            app.TransmitantennasEditFieldLabel = uilabel(app.UIFigure);
            app.TransmitantennasEditFieldLabel.Text = 'Transmit antennas';
            app.TransmitantennasEditField = uieditfield(app.UIFigure, 'numeric');
            app.TransmitantennasEditField.Limits = [1 Inf];
            app.TransmitantennasEditField.Enable = 'off';
            app.TransmitantennasEditField.Value = 1;
            app.SpacetimestreamsEditFieldLabel = uilabel(app.UIFigure);
            app.SpacetimestreamsEditFieldLabel.Text = 'Space-time streams';
            app.SpacetimestreamsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpacetimestreamsEditField.Limits = [1 Inf];
            app.SpacetimestreamsEditField.Enable = 'off';
            app.SpacetimestreamsEditField.Value = 1;
            app.SpatialmappingDropDownLabel = uilabel(app.UIFigure);
            app.SpatialmappingDropDownLabel.Text = 'Spatial mapping';
            app.SpatialmappingDropDown = uidropdown(app.UIFigure);
            app.SpatialmappingDropDown.Items = {'Direct', 'Hadamard', 'Fourier', 'Custom'};
            app.SpatialmappingDropDown.Value = 'Direct';
            app.MCSDropDownLabel = uilabel(app.UIFigure);
            app.MCSDropDownLabel.Text = 'MCS';
            app.MCSDropDown = uidropdown(app.UIFigure);
            app.MCSDropDown.Items = {'0 (BPSK, 1/2 rate)', '1 (QPSK, 1/2 rate)', '2 (QPSK, 3/4 rate)', '3 (16-QAM, 1/2 rate)', '4 (16-QAM, 3/4 rate)', '5 (64-QAM, 2/3 rate)', '6 (64-QAM, 3/4 rate)', '7 (64-QAM, 5/6 rate)', '8 (256-QAM, 3/4 rate)', '9 (256-QAM, 5/6 rate)', '10 (1024-QAM, 3/4 rate)', '11 (1024-QAM, 5/6 rate)', '12 (4096-QAM, 3/4 rate)', '13 (4096-QAM, 5/6 rate)', '15 (BPSK-DCM, 1/2 rate)'};
            app.MCSDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '15'};
            app.MCSDropDown.DropDownOpeningFcn = createCallbackFcn(app, @MCSDropDownOpening, true);
            app.MCSDropDown.ValueChangedFcn = createCallbackFcn(app, @MCSDropDownValueChanged, true);
            app.MCSDropDown.Value = '0';
            app.ChannelcodingDropDownLabel = uilabel(app.UIFigure);
            app.ChannelcodingDropDownLabel.Text = 'Channel coding';
            app.ChannelcodingDropDown = uidropdown(app.UIFigure);
            app.ChannelcodingDropDown.Items = {'LDPC', 'BCC'};
            app.ChannelcodingDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelcodingDropDownValueChanged, true);
            app.ChannelcodingDropDown.Value = 'LDPC';
            app.PreFECpaddingfactorEditFieldLabel = uilabel(app.UIFigure);
            app.PreFECpaddingfactorEditFieldLabel.Text = 'Pre FEC padding factor';
            app.PreFECpaddingfactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.PreFECpaddingfactorEditField.Limits = [1 4];
            app.PreFECpaddingfactorEditField.Value = 4;
            app.LDPCextrasymbolCheckBox = uicheckbox(app.UIFigure);
            app.LDPCextrasymbolCheckBox.Text = 'LDPC extra symbol';
            app.NumExtraEHTLTFsymbolsDropDownLabel = uilabel(app.UIFigure);
            app.NumExtraEHTLTFsymbolsDropDownLabel.Text = 'Num Extra EHT-LTF symbols';
            app.NumExtraEHTLTFsymbolsDropDown = uidropdown(app.UIFigure);
            app.NumExtraEHTLTFsymbolsDropDown.Items = {'1', '2', '4', '6', '8'};
            app.NumExtraEHTLTFsymbolsDropDown.Value = '1';
            app.BSScolorEditFieldLabel = uilabel(app.UIFigure);
            app.BSScolorEditFieldLabel.Text = 'BSS color';
            app.BSScolorEditField = uieditfield(app.UIFigure, 'numeric');
            app.BSScolorEditField.Limits = [0 63];
            app.TXOPdurationEditFieldLabel = uilabel(app.UIFigure);
            app.TXOPdurationEditFieldLabel.Text = 'TXOP duration';
            app.TXOPdurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.TXOPdurationEditField.Limits = [0 8448];
            app.TXOPdurationEditField.Value = 127;
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = be_waveform_generator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end